classdef GPR_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        LsHEditFieldLabel      matlab.ui.control.Label
        LsHEditField           matlab.ui.control.NumericEditField
        PEditFieldLabel        matlab.ui.control.Label
        PEditField             matlab.ui.control.NumericEditField
        vEditFieldLabel        matlab.ui.control.Label
        vEditField             matlab.ui.control.NumericEditField
        PPbEditFieldLabel      matlab.ui.control.Label
        PPbEditField           matlab.ui.control.NumericEditField
        fyEditFieldLabel       matlab.ui.control.Label
        fyEditField            matlab.ui.control.NumericEditField
        fcEditFieldLabel       matlab.ui.control.Label
        fcEditField            matlab.ui.control.NumericEditField
        roEditFieldLabel       matlab.ui.control.Label
        roEditField            matlab.ui.control.NumericEditField
        dbEditFieldLabel       matlab.ui.control.Label
        dbEditField            matlab.ui.control.NumericEditField
        alEditFieldLabel       matlab.ui.control.Label
        alEditField            matlab.ui.control.NumericEditField
        SnEditFieldLabel       matlab.ui.control.Label
        SnEditField            matlab.ui.control.NumericEditField
        roshEditFieldLabel     matlab.ui.control.Label
        roshEditField          matlab.ui.control.NumericEditField
        AshEditFieldLabel      matlab.ui.control.Label
        AshEditField           matlab.ui.control.NumericEditField
        rosheffEditFieldLabel  matlab.ui.control.Label
        rosheffEditField       matlab.ui.control.NumericEditField
        aslEditFieldLabel      matlab.ui.control.Label
        aslEditField           matlab.ui.control.NumericEditField
        fyshEditFieldLabel     matlab.ui.control.Label
        fyshEditField          matlab.ui.control.NumericEditField
        sbydEditFieldLabel     matlab.ui.control.Label
        sbydEditField          matlab.ui.control.NumericEditField
        AEditFieldLabel        matlab.ui.control.Label
        AEditField             matlab.ui.control.NumericEditField
        VnEditFieldLabel       matlab.ui.control.Label
        VnEditField            matlab.ui.control.NumericEditField
        EditField_19Label      matlab.ui.control.Label
        EditField_19           matlab.ui.control.NumericEditField
        sEditFieldLabel        matlab.ui.control.Label
        sEditField             matlab.ui.control.NumericEditField
        Panel2                 matlab.ui.container.Panel
        MyEditFieldLabel       matlab.ui.control.Label
        MyEditField            matlab.ui.control.NumericEditField
        EIEditFieldLabel       matlab.ui.control.Label
        EIEditField            matlab.ui.control.NumericEditField
        McbymyEditFieldLabel   matlab.ui.control.Label
        McbymyEditField        matlab.ui.control.NumericEditField
        EI40EditFieldLabel     matlab.ui.control.Label
        EI40EditField          matlab.ui.control.NumericEditField
        ThEditField_2Label     matlab.ui.control.Label
        ThEditField_2          matlab.ui.control.NumericEditField
        thpcEditFieldLabel     matlab.ui.control.Label
        thpcEditField          matlab.ui.control.NumericEditField
        lambaEditFieldLabel    matlab.ui.control.Label
        lambaEditField         matlab.ui.control.NumericEditField
        CalculateButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %LsbyH 	v 	P	PbyPb 	fc 	fy 	ro	AL	db 	s 	sn 	Ash	rosh 	rosheff 	fysh	asl 	sbyd	Vn	A

%            new_pred =[app.LsHEditField.Value app.vEditField.Value app.PEditField.Value app.PPbEditField.Value app.fcEditField.Value app.fyEditField.Value app.roEditField.Value app.alEditField.Value app.dbEditField.Value app.sEditField.Value app.SnEditField.Value app.AshEditField.Value app.roshEditField.Value app.rosheffEditField.Value app.fyshEditField.Value app.aslEditField.Value app.sbydEditField.Value app.VnEditField.Value app.AEditField.Value];
          new_pred =[app.LsHEditField.Value app.vEditField.Value app.fcEditField.Value app.fyEditField.Value app.roEditField.Value app.dbEditField.Value app.SnEditField.Value app.AshEditField.Value app.roshEditField.Value app.rosheffEditField.Value app.fyshEditField.Value app.aslEditField.Value app.VnEditField.Value app.AEditField.Value];
    
           [a1, b1]=gpr_EIybyEIg(new_pred); 
           [a2 b2]=gpr_EI40byEIg(new_pred);
           [a3 b3]=gpr_McbyMy(new_pred);
           [a4 b4]=gpr_Theta_cap_pl(new_pred);
           [a5 b5]=gpr_Theta_Pc(new_pred);
           [a6 b6]=gpr_Lamda(new_pred);
           app.EIEditField.Value=exp(a1);
           app.EI40EditField.Value=exp(a2);
           app.McbymyEditField.Value=exp(a3);
           app.ThEditField_2.Value=exp(a4);
           app.thpcEditField.Value=exp(a5);
           app.lambaEditField.Value=exp(a6);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.LsHEditFieldLabel = uilabel(app.Panel);
            app.LsHEditFieldLabel.Text = 'Ls/H';
            app.LsHEditField = uieditfield(app.Panel, 'numeric');
            app.PEditFieldLabel = uilabel(app.Panel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.Panel, 'numeric');
            app.vEditFieldLabel = uilabel(app.Panel);
            app.vEditFieldLabel.Text = 'v';
            app.vEditField = uieditfield(app.Panel, 'numeric');
            app.PPbEditFieldLabel = uilabel(app.Panel);
            app.PPbEditFieldLabel.Text = 'P/Pb';
            app.PPbEditField = uieditfield(app.Panel, 'numeric');
            app.fyEditFieldLabel = uilabel(app.Panel);
            app.fyEditFieldLabel.Text = 'fy';
            app.fyEditField = uieditfield(app.Panel, 'numeric');
            app.fcEditFieldLabel = uilabel(app.Panel);
            app.fcEditFieldLabel.Text = 'fc';
            app.fcEditField = uieditfield(app.Panel, 'numeric');
            app.roEditFieldLabel = uilabel(app.Panel);
            app.roEditFieldLabel.Text = 'ro';
            app.roEditField = uieditfield(app.Panel, 'numeric');
            app.dbEditFieldLabel = uilabel(app.Panel);
            app.dbEditFieldLabel.Text = 'db';
            app.dbEditField = uieditfield(app.Panel, 'numeric');
            app.alEditFieldLabel = uilabel(app.Panel);
            app.alEditFieldLabel.Text = 'al';
            app.alEditField = uieditfield(app.Panel, 'numeric');
            app.SnEditFieldLabel = uilabel(app.Panel);
            app.SnEditFieldLabel.Text = 'Sn';
            app.SnEditField = uieditfield(app.Panel, 'numeric');
            app.roshEditFieldLabel = uilabel(app.Panel);
            app.roshEditFieldLabel.Text = 'rosh';
            app.roshEditField = uieditfield(app.Panel, 'numeric');
            app.AshEditFieldLabel = uilabel(app.Panel);
            app.AshEditFieldLabel.Text = 'Ash';
            app.AshEditField = uieditfield(app.Panel, 'numeric');
            app.rosheffEditFieldLabel = uilabel(app.Panel);
            app.rosheffEditFieldLabel.Text = 'rosheff';
            app.rosheffEditField = uieditfield(app.Panel, 'numeric');
            app.aslEditFieldLabel = uilabel(app.Panel);
            app.aslEditFieldLabel.Text = 'asl';
            app.aslEditField = uieditfield(app.Panel, 'numeric');
            app.fyshEditFieldLabel = uilabel(app.Panel);
            app.fyshEditFieldLabel.Text = 'fysh';
            app.fyshEditField = uieditfield(app.Panel, 'numeric');
            app.sbydEditFieldLabel = uilabel(app.Panel);
            app.sbydEditFieldLabel.Text = 'sbyd';
            app.sbydEditField = uieditfield(app.Panel, 'numeric');
            app.AEditFieldLabel = uilabel(app.Panel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.Panel, 'numeric');
            app.VnEditFieldLabel = uilabel(app.Panel);
            app.VnEditFieldLabel.Text = 'Vn';
            app.VnEditField = uieditfield(app.Panel, 'numeric');
            app.EditField_19Label = uilabel(app.Panel);
            app.EditField_19Label.Text = 'Edit Field';
            app.EditField_19 = uieditfield(app.Panel, 'numeric');
            app.sEditFieldLabel = uilabel(app.Panel);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.Panel, 'numeric');
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.MyEditFieldLabel = uilabel(app.Panel2);
            app.MyEditFieldLabel.Text = 'My';
            app.MyEditField = uieditfield(app.Panel2, 'numeric');
            app.EIEditFieldLabel = uilabel(app.Panel2);
            app.EIEditFieldLabel.Text = 'EI';
            app.EIEditField = uieditfield(app.Panel2, 'numeric');
            app.McbymyEditFieldLabel = uilabel(app.Panel2);
            app.McbymyEditFieldLabel.Text = 'Mcbymy';
            app.McbymyEditField = uieditfield(app.Panel2, 'numeric');
            app.EI40EditFieldLabel = uilabel(app.Panel2);
            app.EI40EditFieldLabel.Text = 'EI40';
            app.EI40EditField = uieditfield(app.Panel2, 'numeric');
            app.ThEditField_2Label = uilabel(app.Panel2);
            app.ThEditField_2Label.Text = 'Th';
            app.ThEditField_2 = uieditfield(app.Panel2, 'numeric');
            app.thpcEditFieldLabel = uilabel(app.Panel2);
            app.thpcEditFieldLabel.Text = 'thpc';
            app.thpcEditField = uieditfield(app.Panel2, 'numeric');
            app.lambaEditFieldLabel = uilabel(app.Panel2);
            app.lambaEditFieldLabel.Text = 'lamba';
            app.lambaEditField = uieditfield(app.Panel2, 'numeric');
            app.CalculateButton = uibutton(app.Panel2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GPR_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end