classdef GPR_app_with_basics_ultipage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image                        matlab.ui.control.Image
        EffectiveDepthdEditField     matlab.ui.control.NumericEditField
        EffectivelengthoftheMemberEditFieldLabel  matlab.ui.control.Label
        EffectivelengthoftheMemberEditField  matlab.ui.control.NumericEditField
        AxialLoadEditFieldLabel      matlab.ui.control.Label
        AxialLoadEditField           matlab.ui.control.NumericEditField
        CrossSectionDepthDEditFieldLabel  matlab.ui.control.Label
        CrossSectionDepthDEditField  matlab.ui.control.NumericEditField
        CrossSectionWidthBEditFieldLabel  matlab.ui.control.Label
        CrossSectionWidthBEditField  matlab.ui.control.NumericEditField
        ConceteStrengthfcEditFieldLabel  matlab.ui.control.Label
        ConceteStrengthfcEditField   matlab.ui.control.NumericEditField
        SteelStrengthEditFieldLabel  matlab.ui.control.Label
        SteelStrengthEditField       matlab.ui.control.NumericEditField
        ClearCoverEditFieldLabel     matlab.ui.control.Label
        ClearCoverEditField          matlab.ui.control.NumericEditField
        NumberofBarsinTensionZoneEditFieldLabel  matlab.ui.control.Label
        NumberofBarsinTensionZoneEditField  matlab.ui.control.NumericEditField
        NumberofBarsinCompressionZoneEditFieldLabel  matlab.ui.control.Label
        NumberofBarsinCompressionZoneEditField  matlab.ui.control.NumericEditField
        DiameterofLongitudinalBarEditFieldLabel  matlab.ui.control.Label
        DiameterofLongitudinalBarEditField  matlab.ui.control.NumericEditField
        NumberofTransverseTiesEditFieldLabel  matlab.ui.control.Label
        NumberofTransverseTiesEditField  matlab.ui.control.NumericEditField
        DiameterofTranverseBarsEditFieldLabel  matlab.ui.control.Label
        DiameterofTranverseBarsEditField  matlab.ui.control.NumericEditField
        TransversesteelStrengthEditFieldLabel  matlab.ui.control.Label
        TransversesteelStrengthEditField  matlab.ui.control.NumericEditField
        aslEditFieldLabel            matlab.ui.control.Label
        aslEditField                 matlab.ui.control.NumericEditField
        TranverseReinsorcementSpacingEditFieldLabel  matlab.ui.control.Label
        TranverseReinsorcementSpacingEditField  matlab.ui.control.NumericEditField
        MyEditField                  matlab.ui.control.NumericEditField
        EIEditField                  matlab.ui.control.NumericEditField
        McbymyEditField              matlab.ui.control.NumericEditField
        EI40EditField                matlab.ui.control.NumericEditField
        ThEditField_2                matlab.ui.control.NumericEditField
        pcEditField                  matlab.ui.control.NumericEditField
        lambaEditField               matlab.ui.control.NumericEditField
        sigmaEditField               matlab.ui.control.NumericEditField
        sigmaEditField_2             matlab.ui.control.NumericEditField
        sigmaEditField_3             matlab.ui.control.NumericEditField
        sigmaEditField_4             matlab.ui.control.NumericEditField
        sigmaEditField_5             matlab.ui.control.NumericEditField
        sigmaEditField_7             matlab.ui.control.NumericEditField
        sigmaEditField_6             matlab.ui.control.NumericEditField
        CalculateButton              matlab.ui.control.Button
        EffectiveDepthdLabel         matlab.ui.control.Label
        PredictionofBeamColumnModelParametersLabel  matlab.ui.control.Label
        BackButton                   matlab.ui.control.Button
        lnLabel                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        lnLabel_2                    matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        lnLabel_3                    matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        lnLabel_4                    matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        lnLabel_5                    matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        lnLabel_6                    matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        lnLabel_7                    matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        EditField_3Label             matlab.ui.control.Label
        yLabel                       matlab.ui.control.Label
        MLabel                       matlab.ui.control.Label
        yLabel_2                     matlab.ui.control.Label
        EILabel                      matlab.ui.control.Label
        capplLabel                   matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        EILabel_2                    matlab.ui.control.Label
        MLabel_2                     matlab.ui.control.Label
        Label_10                     matlab.ui.control.Label
        cLabel                       matlab.ui.control.Label
        yLabel_3                     matlab.ui.control.Label
        MLabel_3                     matlab.ui.control.Label
        pcLabel                      matlab.ui.control.Label
        Label_11                     matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %LsbyH 	v 	P	PbyPb 	fc 	fy 	ro	AL	db 	s 	sn 	Ash	rosh 	rosheff 	fysh	asl 	sbyd	Vn	A

%            new_pred =[app.EffectivelengthoftheMemberEditField.Value app.AxialLoadEditField.Value app.CrossSectionDepthDEditField.Value app.CrossSectionWidthBEditField.Value app.ConceteStrengthfcEditField.Value app.SteelStrengthEditField.Value app.NumberofBarsinTensionZoneEditField.Value app.NumberofBarsinCompressionZoneEditField.Value app.DiameterofLongitudinalBarEditField.Value app.TranverseReinsorcementSpacingEditField.Value app.sigmaEditField_6.Value app.AshEditField.Value app.roshEditField.Value app.rosheffEditField.Value app.TransversesteelStrengthEditField.Value app.aslEditField.Value app.NumberofTransverseTiesEditField.Value app.VnEditField.Value app.AEditField.Value];
%           new_pred =[app.EffectivelengthoftheMemberEditField.Value app.AxialLoadEditField.Value app.ConceteStrengthfcEditField.Value app.SteelStrengthEditField.Value app.NumberofBarsinTensionZoneEditField.Value app.DiameterofLongitudinalBarEditField.Value app.sigmaEditField_6.Value app.AshEditField.Value app.roshEditField.Value app.rosheffEditField.Value app.TransversesteelStrengthEditField.Value app.aslEditField.Value app.VnEditField.Value app.AEditField.Value];
            LsbyH= app.EffectivelengthoftheMemberEditField.Value/app.CrossSectionDepthDEditField.Value;
            B=app.CrossSectionWidthBEditField.Value;
            D=app.CrossSectionDepthDEditField.Value;
            A=app.CrossSectionWidthBEditField.Value*app.CrossSectionDepthDEditField.Value;
            d=app.EffectiveDepthdEditField.Value;
            fc=app.ConceteStrengthfcEditField.Value;
            v= app.AxialLoadEditField.Value/(fc*app.CrossSectionWidthBEditField.Value*d);
            fy=app.SteelStrengthEditField.Value;
            db=app.DiameterofLongitudinalBarEditField.Value;
           ALT= app.NumberofBarsinTensionZoneEditField.Value*0.25*pi*db^2;
           ALB=app.NumberofBarsinCompressionZoneEditField.Value*0.25*pi*db^2;
           P=app.AxialLoadEditField.Value;
           app.EffectiveDepthdEditField
           Bd = B.*d;
A =B*D;
Ig_col=(B.*D.^3)/12;
v = P*1000./(fc*A);
Ec=57000*sqrt(fc*145.03774)/145.03774;
Es=199947961497/10^6;
n=Es/Ec;

eps_c = (1.8*fc/Ec);
eps_y = (fy/Es);
cb = (eps_c.*d)./(eps_c+eps_y);
beta1 = min(max(0.65,(1.05-0.05*fc)),0.85);
Pb_col=0.85*fc*(B.*cb.*beta1); %Balanced Axial load 
ro_t= ALB./Bd;
ro_c = ALT./Bd;
ro_v= 0;
ro= ro_t+ro_c;
cc=app.ClearCoverEditField.Value;
delta_desh=cc/d;
Atens = ro_t+ro_c+ro_v+(P/(fy*Bd*0.001)); 
Btens = ro_t+((ro_c*cc)/d)+0.5*(ro_v*(1+cc./d)) + (P/(fy*Bd*0.001));
Acompr = ro_t+ro_c+ro_v-(P/(1.8*n*fc*Bd));
Bcompr = ro_t+((ro_c*cc)/d)+0.5*(ro_v*(1+cc/d));

 if (P<Pb_col)
     ten_ctr=1;
 else
     ten_ctr=0;
 end

 if (ten_ctr==1)
     A_col = Atens;
     B_col = Btens;
 else
   A_col = Acompr;
   B_col = Bcompr ;
 end

ky_col = sqrt((n^2)*A_col.^2 + 2*n*B_col)-n*A_col;


 if (ten_ctr==1)
    fi_ytens_col = (fy/Es)*((1-ky_col).*d).^-1;
 else
  fi_ytens_col = (1.8*fc/Ec)*(ky_col.*d).^-1;
 end

ro_comb =(1-ky_col).*ro_t+(ky_col -cc./d).*ro_c+(1-cc./d).*ro_v*0.1666666666;


%%%%%%
dbs=app.DiameterofTranverseBarsEditField.Value;
Ash=app.NumberofTransverseTiesEditField.Value*0.25*pi*dbs^2;
s=app.TranverseReinsorcementSpacingEditField.Value;
rosh= Ash./(s.*B);
Sn = s/db*(fy/100)^0.5;     
fysh=app.TransversesteelStrengthEditField.Value;
rosh_eff=rosh*fysh/fc;

%%
if (ro>0.012)
    Vc=0.00444822160000003*2*sqrt(fc*0.145038*1000)*Bd/645.16;
else 
    Vc=0.00444822160000003*100*ro*sqrt(fc*0.145038*1000)*Bd/645.16;
end
 Vs=Ash*fysh*d/(s*1000);
 
 Vn=Vc+Vs;
 
 asl=app.aslEditField.Value;
 new_pred=[LsbyH,v,fc,fy,ro,db,Sn,Ash,rosh,rosh_eff,fysh,asl,Vn,A];

 %%%%%%%%%%%%%%%%%%
 new_pred_my=[ky_col,fi_ytens_col,delta_desh,fc,fy,ro_comb,ro,A,v];
           [a1, b1]=gpr_EIybyEIg(new_pred); 
           [a2 b2]=gpr_EI40byEIg(new_pred);
           [a3 b3]=gpr_McbyMy(new_pred);
           [a4 b4]=gpr_Theta_cap_pl(new_pred);
           [a5 b5]=gpr_Theta_Pc(new_pred);
           [a6 b6]=gpr_Lamda(new_pred);
           [a7 b7]=gpr_My(new_pred_my);
           app.EIEditField.Value=exp(a1);
           app.EI40EditField.Value=exp(a2);
           app.McbymyEditField.Value=exp(a3);
           app.ThEditField_2.Value=exp(a4);
           app.pcEditField.Value=exp(a5);
           app.lambaEditField.Value=exp(a6);
           app.MyEditField.Value=exp(a7)*B*d^3*10^-6;
           %% %%%%%%%%
 app.sigmaEditField.Value=b7;
 app.sigmaEditField_2.Value=b1;
 app.sigmaEditField_3.Value=b2;
 app.sigmaEditField_4.Value=b3;
 app.sigmaEditField_5.Value=b4;
 app.sigmaEditField_7.Value=b5;
 app.sigmaEditField_6.Value=b6;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
             app1
             delete(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'blue-abstract-gradient-wave-wallpaper.jpg';
            app.EffectiveDepthdEditField = uieditfield(app.UIFigure, 'numeric');
            app.EffectivelengthoftheMemberEditFieldLabel = uilabel(app.UIFigure);
            app.EffectivelengthoftheMemberEditFieldLabel.Text = 'Effective length of the Member';
            app.EffectivelengthoftheMemberEditField = uieditfield(app.UIFigure, 'numeric');
            app.AxialLoadEditFieldLabel = uilabel(app.UIFigure);
            app.AxialLoadEditFieldLabel.Text = 'Axial Load';
            app.AxialLoadEditField = uieditfield(app.UIFigure, 'numeric');
            app.CrossSectionDepthDEditFieldLabel = uilabel(app.UIFigure);
            app.CrossSectionDepthDEditFieldLabel.Text = 'Cross Section Depth (D)';
            app.CrossSectionDepthDEditField = uieditfield(app.UIFigure, 'numeric');
            app.CrossSectionWidthBEditFieldLabel = uilabel(app.UIFigure);
            app.CrossSectionWidthBEditFieldLabel.Text = 'Cross Section Width (B)';
            app.CrossSectionWidthBEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConceteStrengthfcEditFieldLabel = uilabel(app.UIFigure);
            app.ConceteStrengthfcEditFieldLabel.Text = 'Concete Strength (fc)';
            app.ConceteStrengthfcEditField = uieditfield(app.UIFigure, 'numeric');
            app.SteelStrengthEditFieldLabel = uilabel(app.UIFigure);
            app.SteelStrengthEditFieldLabel.Text = 'Steel Strength';
            app.SteelStrengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClearCoverEditFieldLabel = uilabel(app.UIFigure);
            app.ClearCoverEditFieldLabel.Text = 'Clear Cover';
            app.ClearCoverEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofBarsinTensionZoneEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofBarsinTensionZoneEditFieldLabel.Text = 'Number of Bars in Tension Zone';
            app.NumberofBarsinTensionZoneEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofBarsinCompressionZoneEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofBarsinCompressionZoneEditFieldLabel.Text = 'Number of Bars in Compression Zone';
            app.NumberofBarsinCompressionZoneEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiameterofLongitudinalBarEditFieldLabel = uilabel(app.UIFigure);
            app.DiameterofLongitudinalBarEditFieldLabel.Text = 'Diameter of Longitudinal Bar';
            app.DiameterofLongitudinalBarEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofTransverseTiesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofTransverseTiesEditFieldLabel.Text = 'Number of Transverse Ties';
            app.NumberofTransverseTiesEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiameterofTranverseBarsEditFieldLabel = uilabel(app.UIFigure);
            app.DiameterofTranverseBarsEditFieldLabel.Text = 'Diameter of Tranverse Bars';
            app.DiameterofTranverseBarsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransversesteelStrengthEditFieldLabel = uilabel(app.UIFigure);
            app.TransversesteelStrengthEditFieldLabel.Text = 'Transverse steel Strength';
            app.TransversesteelStrengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.aslEditFieldLabel = uilabel(app.UIFigure);
            app.aslEditFieldLabel.Text = 'asl';
            app.aslEditField = uieditfield(app.UIFigure, 'numeric');
            app.TranverseReinsorcementSpacingEditFieldLabel = uilabel(app.UIFigure);
            app.TranverseReinsorcementSpacingEditFieldLabel.Text = 'Tranverse Reinsorcement Spacing';
            app.TranverseReinsorcementSpacingEditField = uieditfield(app.UIFigure, 'numeric');
            app.MyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EIEditField = uieditfield(app.UIFigure, 'numeric');
            app.McbymyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EI40EditField = uieditfield(app.UIFigure, 'numeric');
            app.ThEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.pcEditField = uieditfield(app.UIFigure, 'numeric');
            app.lambaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.EffectiveDepthdLabel = uilabel(app.UIFigure);
            app.EffectiveDepthdLabel.Text = 'Effective Depth (d)';
            app.PredictionofBeamColumnModelParametersLabel = uilabel(app.UIFigure);
            app.PredictionofBeamColumnModelParametersLabel.Text = 'Prediction of Beam Column Model Parameters';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.lnLabel = uilabel(app.UIFigure);
            app.lnLabel.Text = 'ln';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'σ';
            app.lnLabel_2 = uilabel(app.UIFigure);
            app.lnLabel_2.Text = 'ln';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'σ';
            app.lnLabel_3 = uilabel(app.UIFigure);
            app.lnLabel_3.Text = 'ln';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'σ';
            app.lnLabel_4 = uilabel(app.UIFigure);
            app.lnLabel_4.Text = 'ln';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'σ';
            app.lnLabel_5 = uilabel(app.UIFigure);
            app.lnLabel_5.Text = 'ln';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'σ';
            app.lnLabel_6 = uilabel(app.UIFigure);
            app.lnLabel_6.Text = 'ln';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'σ';
            app.lnLabel_7 = uilabel(app.UIFigure);
            app.lnLabel_7.Text = 'ln';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'σ';
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'λ';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.MLabel = uilabel(app.UIFigure);
            app.MLabel.Text = 'M';
            app.yLabel_2 = uilabel(app.UIFigure);
            app.yLabel_2.Text = 'y';
            app.EILabel = uilabel(app.UIFigure);
            app.EILabel.Text = 'EI';
            app.capplLabel = uilabel(app.UIFigure);
            app.capplLabel.Text = 'cap,pl';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'θ';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '40';
            app.EILabel_2 = uilabel(app.UIFigure);
            app.EILabel_2.Text = 'EI';
            app.MLabel_2 = uilabel(app.UIFigure);
            app.MLabel_2.Text = 'M';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '/';
            app.cLabel = uilabel(app.UIFigure);
            app.cLabel.Text = 'c';
            app.yLabel_3 = uilabel(app.UIFigure);
            app.yLabel_3.Text = 'y';
            app.MLabel_3 = uilabel(app.UIFigure);
            app.MLabel_3.Text = 'M';
            app.pcLabel = uilabel(app.UIFigure);
            app.pcLabel.Text = 'pc';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'θ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GPR_app_with_basics_ultipage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end