classdef GPR_drived_variables < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Image                  matlab.ui.control.Image
        LsHEditFieldLabel      matlab.ui.control.Label
        LsHEditField           matlab.ui.control.NumericEditField
        PEditFieldLabel        matlab.ui.control.Label
        PEditField             matlab.ui.control.NumericEditField
        vEditFieldLabel        matlab.ui.control.Label
        vEditField             matlab.ui.control.NumericEditField
        PPbEditFieldLabel      matlab.ui.control.Label
        PPbEditField           matlab.ui.control.NumericEditField
        fyEditFieldLabel       matlab.ui.control.Label
        fyEditField            matlab.ui.control.NumericEditField
        fcEditFieldLabel       matlab.ui.control.Label
        fcEditField            matlab.ui.control.NumericEditField
        roEditFieldLabel       matlab.ui.control.Label
        roEditField            matlab.ui.control.NumericEditField
        dbEditFieldLabel       matlab.ui.control.Label
        dbEditField            matlab.ui.control.NumericEditField
        alEditFieldLabel       matlab.ui.control.Label
        alEditField            matlab.ui.control.NumericEditField
        SnEditFieldLabel       matlab.ui.control.Label
        SnEditField            matlab.ui.control.NumericEditField
        roshEditFieldLabel     matlab.ui.control.Label
        roshEditField          matlab.ui.control.NumericEditField
        AshEditFieldLabel      matlab.ui.control.Label
        AshEditField           matlab.ui.control.NumericEditField
        rosheffEditFieldLabel  matlab.ui.control.Label
        rosheffEditField       matlab.ui.control.NumericEditField
        aslEditFieldLabel      matlab.ui.control.Label
        aslEditField           matlab.ui.control.NumericEditField
        fyshEditFieldLabel     matlab.ui.control.Label
        fyshEditField          matlab.ui.control.NumericEditField
        sbydEditFieldLabel     matlab.ui.control.Label
        sbydEditField          matlab.ui.control.NumericEditField
        AEditFieldLabel        matlab.ui.control.Label
        AEditField             matlab.ui.control.NumericEditField
        VnEditFieldLabel       matlab.ui.control.Label
        VnEditField            matlab.ui.control.NumericEditField
        sEditFieldLabel        matlab.ui.control.Label
        sEditField             matlab.ui.control.NumericEditField
        PredictionofBeamColumnModelParametersLabel  matlab.ui.control.Label
        CalculateButton        matlab.ui.control.Button
        MyEditField            matlab.ui.control.NumericEditField
        EIEditField            matlab.ui.control.NumericEditField
        McbymyEditField        matlab.ui.control.NumericEditField
        EI40EditField          matlab.ui.control.NumericEditField
        ThEditField            matlab.ui.control.NumericEditField
        thpcEditField          matlab.ui.control.NumericEditField
        lambaEditField         matlab.ui.control.NumericEditField
        BackButton             matlab.ui.control.Button
        sigmaEditField         matlab.ui.control.NumericEditField
        sigmaEditField_2       matlab.ui.control.NumericEditField
        sigmaEditField_3       matlab.ui.control.NumericEditField
        sigmaEditField_4       matlab.ui.control.NumericEditField
        sigmaEditField_5       matlab.ui.control.NumericEditField
        sigmaEditField_6       matlab.ui.control.NumericEditField
        sigmaEditField_7       matlab.ui.control.NumericEditField
        EditField_3Label       matlab.ui.control.Label
        yLabel                 matlab.ui.control.Label
        MLabel                 matlab.ui.control.Label
        yLabel_2               matlab.ui.control.Label
        EILabel                matlab.ui.control.Label
        capplLabel             matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        EILabel_2              matlab.ui.control.Label
        MLabel_2               matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        cLabel                 matlab.ui.control.Label
        yLabel_3               matlab.ui.control.Label
        MLabel_3               matlab.ui.control.Label
        pcLabel                matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        lnLabel                matlab.ui.control.Label
        Label_5                matlab.ui.control.Label
        lnLabel_2              matlab.ui.control.Label
        Label_6                matlab.ui.control.Label
        lnLabel_3              matlab.ui.control.Label
        Label_7                matlab.ui.control.Label
        lnLabel_4              matlab.ui.control.Label
        Label_8                matlab.ui.control.Label
        lnLabel_5              matlab.ui.control.Label
        Label_9                matlab.ui.control.Label
        lnLabel_6              matlab.ui.control.Label
        Label_10               matlab.ui.control.Label
        lnLabel_7              matlab.ui.control.Label
        Label_11               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %LsbyH 	v 	P	PbyPb 	fc 	fy 	ro	AL	db 	s 	sn 	Ash	rosh 	rosheff 	fysh	asl 	sbyd	Vn	A

%            new_pred =[app.LsHEditField.Value app.vEditField.Value app.PEditField.Value app.PPbEditField.Value app.fcEditField.Value app.fyEditField.Value app.roEditField.Value app.alEditField.Value app.dbEditField.Value app.sEditField.Value app.SnEditField.Value app.AshEditField.Value app.roshEditField.Value app.rosheffEditField.Value app.fyshEditField.Value app.aslEditField.Value app.sbydEditField.Value app.VnEditField.Value app.AEditField.Value];
          new_pred =[app.LsHEditField.Value app.vEditField.Value app.fcEditField.Value app.fyEditField.Value app.roEditField.Value app.dbEditField.Value app.SnEditField.Value app.AshEditField.Value app.roshEditField.Value app.rosheffEditField.Value app.fyshEditField.Value app.aslEditField.Value app.VnEditField.Value app.AEditField.Value];
    
           [a1, b1]=gpr_EIybyEIg(new_pred); 
           [a2 b2]=gpr_EI40byEIg(new_pred);
           [a3 b3]=gpr_McbyMy(new_pred);
           [a4 b4]=gpr_Theta_cap_pl(new_pred);
           [a5 b5]=gpr_Theta_Pc(new_pred);
           [a6 b6]=gpr_Lamda(new_pred);
           app.EIEditField.Value=exp(a1);
           app.EI40EditField.Value=exp(a2);
           app.McbymyEditField.Value=exp(a3);
           app.ThEditField_2.Value=exp(a4);
           app.thpcEditField.Value=exp(a5);
           app.lambaEditField.Value=exp(a6);

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
              app1
             delete(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'blue-abstract-gradient-wave-wallpaper.jpg';
            app.LsHEditFieldLabel = uilabel(app.UIFigure);
            app.LsHEditFieldLabel.Text = 'Ls/H';
            app.LsHEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.vEditFieldLabel = uilabel(app.UIFigure);
            app.vEditFieldLabel.Text = 'v';
            app.vEditField = uieditfield(app.UIFigure, 'numeric');
            app.PPbEditFieldLabel = uilabel(app.UIFigure);
            app.PPbEditFieldLabel.Text = 'P/Pb';
            app.PPbEditField = uieditfield(app.UIFigure, 'numeric');
            app.fyEditFieldLabel = uilabel(app.UIFigure);
            app.fyEditFieldLabel.Text = 'fy';
            app.fyEditField = uieditfield(app.UIFigure, 'numeric');
            app.fcEditFieldLabel = uilabel(app.UIFigure);
            app.fcEditFieldLabel.Text = 'fc';
            app.fcEditField = uieditfield(app.UIFigure, 'numeric');
            app.roEditFieldLabel = uilabel(app.UIFigure);
            app.roEditFieldLabel.Text = 'ro';
            app.roEditField = uieditfield(app.UIFigure, 'numeric');
            app.dbEditFieldLabel = uilabel(app.UIFigure);
            app.dbEditFieldLabel.Text = 'db';
            app.dbEditField = uieditfield(app.UIFigure, 'numeric');
            app.alEditFieldLabel = uilabel(app.UIFigure);
            app.alEditFieldLabel.Text = 'al';
            app.alEditField = uieditfield(app.UIFigure, 'numeric');
            app.SnEditFieldLabel = uilabel(app.UIFigure);
            app.SnEditFieldLabel.Text = 'Sn';
            app.SnEditField = uieditfield(app.UIFigure, 'numeric');
            app.roshEditFieldLabel = uilabel(app.UIFigure);
            app.roshEditFieldLabel.Text = 'rosh';
            app.roshEditField = uieditfield(app.UIFigure, 'numeric');
            app.AshEditFieldLabel = uilabel(app.UIFigure);
            app.AshEditFieldLabel.Text = 'Ash';
            app.AshEditField = uieditfield(app.UIFigure, 'numeric');
            app.rosheffEditFieldLabel = uilabel(app.UIFigure);
            app.rosheffEditFieldLabel.Text = 'rosheff';
            app.rosheffEditField = uieditfield(app.UIFigure, 'numeric');
            app.aslEditFieldLabel = uilabel(app.UIFigure);
            app.aslEditFieldLabel.Text = 'asl';
            app.aslEditField = uieditfield(app.UIFigure, 'numeric');
            app.fyshEditFieldLabel = uilabel(app.UIFigure);
            app.fyshEditFieldLabel.Text = 'fysh';
            app.fyshEditField = uieditfield(app.UIFigure, 'numeric');
            app.sbydEditFieldLabel = uilabel(app.UIFigure);
            app.sbydEditFieldLabel.Text = 'sbyd';
            app.sbydEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.VnEditFieldLabel = uilabel(app.UIFigure);
            app.VnEditFieldLabel.Text = 'Vn';
            app.VnEditField = uieditfield(app.UIFigure, 'numeric');
            app.sEditFieldLabel = uilabel(app.UIFigure);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.UIFigure, 'numeric');
            app.PredictionofBeamColumnModelParametersLabel = uilabel(app.UIFigure);
            app.PredictionofBeamColumnModelParametersLabel.Text = 'Prediction of Beam Column Model Parameters';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EIEditField = uieditfield(app.UIFigure, 'numeric');
            app.McbymyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EI40EditField = uieditfield(app.UIFigure, 'numeric');
            app.ThEditField = uieditfield(app.UIFigure, 'numeric');
            app.thpcEditField = uieditfield(app.UIFigure, 'numeric');
            app.lambaEditField = uieditfield(app.UIFigure, 'numeric');
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'λ';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.MLabel = uilabel(app.UIFigure);
            app.MLabel.Text = 'M';
            app.yLabel_2 = uilabel(app.UIFigure);
            app.yLabel_2.Text = 'y';
            app.EILabel = uilabel(app.UIFigure);
            app.EILabel.Text = 'EI';
            app.capplLabel = uilabel(app.UIFigure);
            app.capplLabel.Text = 'cap,pl';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'θ';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '40';
            app.EILabel_2 = uilabel(app.UIFigure);
            app.EILabel_2.Text = 'EI';
            app.MLabel_2 = uilabel(app.UIFigure);
            app.MLabel_2.Text = 'M';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '/';
            app.cLabel = uilabel(app.UIFigure);
            app.cLabel.Text = 'c';
            app.yLabel_3 = uilabel(app.UIFigure);
            app.yLabel_3.Text = 'y';
            app.MLabel_3 = uilabel(app.UIFigure);
            app.MLabel_3.Text = 'M';
            app.pcLabel = uilabel(app.UIFigure);
            app.pcLabel.Text = 'pc';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'θ';
            app.lnLabel = uilabel(app.UIFigure);
            app.lnLabel.Text = 'ln';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'σ';
            app.lnLabel_2 = uilabel(app.UIFigure);
            app.lnLabel_2.Text = 'ln';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'σ';
            app.lnLabel_3 = uilabel(app.UIFigure);
            app.lnLabel_3.Text = 'ln';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'σ';
            app.lnLabel_4 = uilabel(app.UIFigure);
            app.lnLabel_4.Text = 'ln';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'σ';
            app.lnLabel_5 = uilabel(app.UIFigure);
            app.lnLabel_5.Text = 'ln';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'σ';
            app.lnLabel_6 = uilabel(app.UIFigure);
            app.lnLabel_6.Text = 'ln';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'σ';
            app.lnLabel_7 = uilabel(app.UIFigure);
            app.lnLabel_7.Text = 'ln';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'σ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GPR_drived_variables

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end